classdef VFD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        BrowserVideoButton      matlab.ui.control.Button
        Lamp                    matlab.ui.control.Lamp
        FileNameEditFieldLabel  matlab.ui.control.Label
        FileNameEditField       matlab.ui.control.EditField
        UIAxes                  matlab.ui.control.UIAxes
        Lamp_2                  matlab.ui.control.Lamp
        Lamp_3                  matlab.ui.control.Lamp
        ExportFramesButton      matlab.ui.control.Button
        Lamp_4                  matlab.ui.control.Lamp
        PlayVideoButton         matlab.ui.control.Button
        GaugeLabel              matlab.ui.control.Label
        Gauge                   matlab.ui.control.LinearGauge
    end

    
    properties (Access = private)
        fullpathname  % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Lamp.Color='r';
            app.Lamp_2.Color='r';
            app.Lamp_3.Color='r';
            app.Lamp_4.Color='r';
        end

        % Button pushed function: BrowserVideoButton
        function BrowserVideoButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.mp4'},'File Selector');
            
            app.fullpathname = strcat(pathname,filename);
            app.FileNameEditField.Value = filename;
            
            app.Lamp.Color='g';
        end

        % Value changing function: FileNameEditField
        function FileNameEditFieldValueChanging(app, event)
            changingValue = event.Value;
            app.Lamp.Color='g';
        end

        % Button pushed function: PlayVideoButton
        function PlayVideoButtonPushed(app, event)
            
            v = VideoReader(app.fullpathname);
            %while hasFrame(v)
            %    app.Lamp_3.Color='r';
            %    imshow(getframe(v), 'Parent', app.UIAxes);
            %    app.Lamp_3.Color='g';
            %end
            while hasFrame(v)
                vidFrame = readFrame(v);
                image(vidFrame, 'Parent', app.UIAxes);
                app.UIAxes.Visible = 'off';
                pause(1/v.FrameRate);
            end
        end

        % Button pushed function: ExportFramesButton
        function ExportFramesButtonPushed(app, event)
            py.videoToFrame.FrameCapture(app.fullpathname);
            app.Lamp_4.Color='g';
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.BrowserVideoButton = uibutton(app.UIFigure, 'push');
            app.BrowserVideoButton.ButtonPushedFcn = createCallbackFcn(app, @BrowserVideoButtonPushed, true);
            app.BrowserVideoButton.Text = 'Browser Video';
            app.Lamp = uilamp(app.UIFigure);
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.UIFigure, 'text');
            app.FileNameEditField.ValueChangingFcn = createCallbackFcn(app, @FileNameEditFieldValueChanging, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Lamp_3 = uilamp(app.UIFigure);
            app.ExportFramesButton = uibutton(app.UIFigure, 'push');
            app.ExportFramesButton.ButtonPushedFcn = createCallbackFcn(app, @ExportFramesButtonPushed, true);
            app.ExportFramesButton.Text = 'Export Frames';
            app.Lamp_4 = uilamp(app.UIFigure);
            app.PlayVideoButton = uibutton(app.UIFigure, 'push');
            app.PlayVideoButton.ButtonPushedFcn = createCallbackFcn(app, @PlayVideoButtonPushed, true);
            app.PlayVideoButton.Text = 'Play Video';
            app.GaugeLabel = uilabel(app.UIFigure);
            app.GaugeLabel.Text = 'Gauge';
            app.Gauge = uigauge(app.UIFigure, 'linear');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VFD

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end