classdef structure < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        VideoForgeryDetectionPanel  matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
        SelectFileButton            matlab.ui.control.Button
        FilterframesButton          matlab.ui.control.Button
        BlockWiseCorrelationCoefficientButton  matlab.ui.control.Button
        EditField_4Label            matlab.ui.control.Label
        EditField_4                 matlab.ui.control.EditField
        EditField_5Label            matlab.ui.control.Label
        EditField_5                 matlab.ui.control.EditField
        EditField_6Label            matlab.ui.control.Label
        EditField_6                 matlab.ui.control.EditField
        x8blockdivisionButton       matlab.ui.control.Button
        EditField_3Label            matlab.ui.control.Label
        EditField_3                 matlab.ui.control.EditField
        ThresholdFittingButton      matlab.ui.control.Button
        ExtractFramesButton         matlab.ui.control.Button
        GlcmFeaturesButton          matlab.ui.control.Button
        EditFieldLabel              matlab.ui.control.Label
        EditField                   matlab.ui.control.EditField
        EditField_2Label            matlab.ui.control.Label
        EditField_2                 matlab.ui.control.EditField
        EditField2Label             matlab.ui.control.Label
        EditField2                  matlab.ui.control.EditField
        PlayButton                  matlab.ui.control.Button
    end

    
    properties (Access = public)
        fullpathname
        % Description
        video
        pathname
        tempPath
        
    end
    
    methods (Access = public)
        
        function filter(~,loc)
            files=dir(strcat(loc,'\*.jpg'));
            L=length(files);
            for i=1:L
                name='frame';
                d=int2str(i);
                df=strcat(name,d);
                ex='.jpg';
                dg=strcat(df,ex);
                comppath=strcat(loc,'\',dg);
                a=rgb2gray(imread(comppath));
                a=medfilt2(a);
                imwrite(a,comppath);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.EditField_6Label.Visible='off';
            app.EditField_6.Visible='off';
            app.EditField_4Label.Visible='off';
            app.EditField_4.Visible='off';
            app.EditField_5Label.Visible='off';
            app.EditField_5.Visible='off';
            app.EditField_3Label.Visible='off';
            app.EditField_3.Visible='off';
            app.EditField_2Label.Visible='off';
            app.EditField_2.Visible='off';
            app.EditFieldLabel.Visible='off';
            app.EditField.Visible='off';
            app.EditField2Label.Visible='off';
            app.EditField2.Visible='off';
            app.UIAxes.Visible='off';
        end

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
            [filename, app.pathname] = uigetfile({'*.mp4'},'File Selector');
            
            app.fullpathname = strcat(app.pathname,filename);
            app.EditField_6.Value = filename;
            app.EditField_6Label.Text='Selected File';
            app.EditField_6Label.Visible='on';
            app.EditField_6.Visible='on';
            app.video=VideoReader(app.fullpathname);
            app.EditField2.Value=char(app.video.Duration);
            app.EditField2Label.Text='Duration';
            app.EditField2Label.Visible='on';
            app.EditField2.Visible='on';
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            v = VideoReader(app.fullpathname);
            %while hasFrame(v)
            %    app.Lamp_3.Color='r';
            %    imshow(getframe(v), 'Parent', app.UIAxes);
            %    app.Lamp_3.Color='g';
            %end
            while hasFrame(v)
                vidFrame = readFrame(v);
                image(vidFrame, 'Parent', app.UIAxes);
                app.UIAxes.Visible = 'off';
                pause(1/v.FrameRate);
            end
        end

        % Button pushed function: ExtractFramesButton
        function ExtractFramesButtonPushed(app, event)
            system('mkdir temp');
            try
                py.videoToFrame.FrameCapture(app.fullpathname);
            catch
                
            end
            app.EditFieldLabel.Text='Extracte in Folder';
            app.tempPath=strcat(app.pathname,'temp');
            app.EditField.Value=app.tempPath;
            app.EditFieldLabel.Visible='on';
            app.EditField.Visible='on';
        end

        % Button pushed function: FilterframesButton
        function FilterframesButtonPushed(app, event)
            loc='temp';
            files=dir(strcat(loc,'\*.jpg'));
            L=length(files);
            
            for i=1:L
                name='frame';
                d=int2str(i-1);
                df=strcat(name,d);
                ex='.jpg';
                dg=strcat(df,ex);
                comppath=strcat(loc,'\',dg);
                a=imread(comppath);
                %a=rgb2gray(a);
                a=medfilt2(a);
                imwrite(a,comppath);
                
            end
            app.EditField_4Label.Text='Filterd in folder';
            app.tempPath=strcat(app.pathname,'temp');
            app.EditField_4.Value=app.tempPath;
            app.EditField_4Label.Visible='on';
            app.EditField_4.Visible='on';
        end

        % Button pushed function: x8blockdivisionButton
        function x8blockdivisionButtonPushed(app, event)
            %system('mkdir 8x8');
            py.divfun.Function_new('temp\');
            app.EditField_3Label.Text='Block Division';
            app.tempPath=strcat(app.pathname,'temp');
            app.EditField_3.Value=app.tempPath;
            app.EditField_3Label.Visible='on';
            app.EditField_3.Visible='on';
        end

        % Button pushed function: GlcmFeaturesButton
        function GlcmFeaturesButtonPushed(app, event)
            glcmdraw
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.VideoForgeryDetectionPanel = uipanel(app.UIFigure);
            app.VideoForgeryDetectionPanel.Title = 'Video Forgery Detection';
            app.UIAxes = uiaxes(app.VideoForgeryDetectionPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.SelectFileButton = uibutton(app.VideoForgeryDetectionPanel, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Icon = 'video.png';
            app.SelectFileButton.Text = 'Select File';
            app.FilterframesButton = uibutton(app.VideoForgeryDetectionPanel, 'push');
            app.FilterframesButton.ButtonPushedFcn = createCallbackFcn(app, @FilterframesButtonPushed, true);
            app.FilterframesButton.Icon = 'filters.png';
            app.FilterframesButton.Text = 'Filter frames';
            app.BlockWiseCorrelationCoefficientButton = uibutton(app.VideoForgeryDetectionPanel, 'push');
            app.BlockWiseCorrelationCoefficientButton.Icon = 'correlation.png';
            app.BlockWiseCorrelationCoefficientButton.Text = {'Block Wise '; 'Correlation'; 'Coefficient'};
            app.EditField_4Label = uilabel(app.VideoForgeryDetectionPanel);
            app.EditField_4Label.Text = 'Edit Field';
            app.EditField_4 = uieditfield(app.VideoForgeryDetectionPanel, 'text');
            app.EditField_4.Editable = 'off';
            app.EditField_5Label = uilabel(app.VideoForgeryDetectionPanel);
            app.EditField_5Label.Text = 'Edit Field';
            app.EditField_5 = uieditfield(app.VideoForgeryDetectionPanel, 'text');
            app.EditField_5.Editable = 'off';
            app.EditField_6Label = uilabel(app.VideoForgeryDetectionPanel);
            app.EditField_6Label.Text = 'Edit Field';
            app.EditField_6 = uieditfield(app.VideoForgeryDetectionPanel, 'text');
            app.EditField_6.Editable = 'off';
            app.x8blockdivisionButton = uibutton(app.VideoForgeryDetectionPanel, 'push');
            app.x8blockdivisionButton.ButtonPushedFcn = createCallbackFcn(app, @x8blockdivisionButtonPushed, true);
            app.x8blockdivisionButton.Icon = 'blockdivision.png';
            app.x8blockdivisionButton.Text = {'8x8 block '; 'division'};
            app.EditField_3Label = uilabel(app.VideoForgeryDetectionPanel);
            app.EditField_3Label.Text = 'Edit Field';
            app.EditField_3 = uieditfield(app.VideoForgeryDetectionPanel, 'text');
            app.EditField_3.Editable = 'off';
            app.ThresholdFittingButton = uibutton(app.VideoForgeryDetectionPanel, 'push');
            app.ThresholdFittingButton.Icon = 'Threshold.png';
            app.ThresholdFittingButton.Text = {'Threshold '; 'Fitting'};
            app.ExtractFramesButton = uibutton(app.VideoForgeryDetectionPanel, 'push');
            app.ExtractFramesButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractFramesButtonPushed, true);
            app.ExtractFramesButton.Icon = 'frames-per-second.png';
            app.ExtractFramesButton.Text = 'Extract Frames';
            app.GlcmFeaturesButton = uibutton(app.VideoForgeryDetectionPanel, 'push');
            app.GlcmFeaturesButton.ButtonPushedFcn = createCallbackFcn(app, @GlcmFeaturesButtonPushed, true);
            app.GlcmFeaturesButton.Icon = 'feature.png';
            app.GlcmFeaturesButton.Text = {'Glcm'; 'Features'};
            app.EditFieldLabel = uilabel(app.VideoForgeryDetectionPanel);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.VideoForgeryDetectionPanel, 'text');
            app.EditField_2Label = uilabel(app.VideoForgeryDetectionPanel);
            app.EditField_2Label.Text = 'Edit Field';
            app.EditField_2 = uieditfield(app.VideoForgeryDetectionPanel, 'text');
            app.EditField_2.Editable = 'off';
            app.EditField2Label = uilabel(app.VideoForgeryDetectionPanel);
            app.EditField2Label.Text = 'Edit Field2';
            app.EditField2 = uieditfield(app.VideoForgeryDetectionPanel, 'text');
            app.PlayButton = uibutton(app.VideoForgeryDetectionPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Icon = 'index.png';
            app.PlayButton.Text = 'Play';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = structure

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end